classdef proje < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure  matlab.ui.Figure
        DorusalHzSliderLabel  matlab.ui.control.Label
        DogrusalHizSlider     matlab.ui.control.Slider
        graph_aci             matlab.ui.control.UIAxes
        graph_hata            matlab.ui.control.UIAxes
        graph_tekerlek_hiz    matlab.ui.control.UIAxes
        graph_kontrol         matlab.ui.control.UIAxes
        graph_vl              matlab.ui.control.UIAxes
        graph_vr              matlab.ui.control.UIAxes
        SimulasyonButton      matlab.ui.control.Button
        KpEditFieldLabel      matlab.ui.control.Label
        Kp                    matlab.ui.control.NumericEditField
        KiEditFieldLabel      matlab.ui.control.Label
        Ki                    matlab.ui.control.NumericEditField
        KdEditField_2Label    matlab.ui.control.Label
        Kd                    matlab.ui.control.NumericEditField
        AKnobLabel            matlab.ui.control.Label
        AciKnob               matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = public)
        myRobot % robotics playground objesi olacak
        running=0; % butona basilip programin calisir durumda oldugunu gosteren degisken
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.myRobot = mlrobot; % program basladiginda bir robot nesnesi olusturur.
            %initial Values
                 
        end

        % Button pushed function: SimulasyonButton
        function SimulasyonButtonPushed(app, event)
            
            if (app.running==1)               
                app.SimulasyonButton.Text = 'Simulasyonu Başlat';
                robot = app.myRobot;
                mlrobotstop(robot); % devam eden simulasyonu durdur
                app.running=0;
            else
                app.SimulasyonButton.Text = 'Durdur';
                app.running=1;
                robot = app.myRobot;
                mlrobotstart(robot); % simulasyonu baslat

                %Değişkenlerin tanımlanması ve ilk değerlerin atanması
                
                T   =  50;                  %  Programın çalışma süresi 
                dt  =  0.5;                 %  delta t zamanı
                t   =  .0:dt:(T);           %  Zaman aralıkları 
                n   =  length(t);           %  t zaman vektörünün boyutu 
            
                P   =  0;                   %  Oransal   Kontrolcü sıfırlandı
                I   =  0;                   %  Integral  Kontrolcü sıfırlandı
                D   =  0;                   %  Türev     Kontrolcü sıfırlandı
                PID =  0;                   %  O-I-T     Kontrolcü sıfırlandı

                        
                Vmin  = -5;                 %  Maksimum gerilim değeri 5 olarak  atandı 
                Vmax  =  5;                 %  Minimum  gerilim değeri -5 olarak atandı
                Error =  0;                 %  Hata değeri sıfırlandı
                
                errorSum = 0;               %  hataların toplamı sıfırlandı
                errorPre = 0;               %  Bir önceki hata değeri sıfırlandı
                errorDif = 0;               %  Bir önceki ve şimdiki hataların farkı sıfırlandı
                
                angleArray  = zeros(1,n);   %  Robotun açısı dizisi oluşturuldu - boyutu n kadar 
                errorArray  = zeros(1,n);   %  Hata dizisi oluşturuldu 
                vlArray     = zeros(1,n);   %  Robotun sol tekerleği gerilim dizisi oluşturuldu     - boyutu n kadar 
                vrArray     = zeros(1,n);   %  Robotun sağ tekerleği gerilim dizisi oluşturuldu     - boyutu n kadar 
                angularVelr = zeros(1,n);   %  Robotun sağ tekerlek açısal hızı dizisi oluşturuldu  - boyutu n kadar 
                angularVell = zeros(1,n);   %  Robotun sol tekerlek açısal hızı dizisi oluşturuldu  - boyutu n kadar
                controlSig  = zeros(1,n);   %  Robotun kontrol sinyali dizisi oluşturuldu           - boyutu n kadar 
                                
                color = rand(1,3);          %  Renk kodu random olarak seçildi
                
                %PROGRAM AKIŞI
                
                for k = 1:n
                   userAngle    =  str2num(app.AciKnob.Value);               % Kullanıcı açısı sayı olarak alındı
                   currentAngle =  deg2rad(mlrobotreadcompassangle(robot));  % Robotun açısı rad olarak alındı  
                                     
                   if(userAngle==3*pi/2 )                                    % Kullanıcı açı girdisi 3*pi/2 ' ye eşit mi?
                      currentAngle  =  currentAngle + pi;                    % Öyle ise şuanki açıya  pi kadar ekle
                      Error         =  -userAngle + currentAngle;            % ve Hatayı hesapla
                   elseif(userAngle>3*pi/2 && userAngle<2*pi)                % kullanıcı açı girdisi 3*pi/2 ile 2*pi arasında mı ?
                       currentAngle =  currentAngle + 3*pi/2 ;               % Öyle ise şuanki açıya 3*pi/2 ekle
                       Error        =  -userAngle + currentAngle;            % ve Hatayı hesapla
                   elseif(userAngle>pi && userAngle<3*pi/2)                  % Kullanıcı açı girdisi pi ile 3*pi/2 arasında mı?
                      currentAngle = currentAngle + 2*(pi - currentAngle);   % Öyle ise açıya 2*(pi - currentAngle) değeri ekle
                      Error        = userAngle - currentAngle;               % Ve hatayı hesapla
                   else    
                       Error       = userAngle - currentAngle;               % Yukarıdaki koşullar sağlanmıyorsa hatayı hesapla
                   end

                   errorSum     =  Error + errorSum;                         % Baştan şu ana kadarki hataların toplamı alındı
                   errorDif     =  errorPre - Error;                         % Bir önceki ve şimdiki hataların farkı alındı                         
                   errorPre     =  Error;                                    % Bir önceki hata güncellendi 
                  
                   P   =  app.Kp.Value*Error;                                % Oransal  Kontrol Sinyali  hesaplandı
                   I   =  app.Ki.Value*errorSum*dt;                          % Integral Kontrol Sinyali  hesaplandı  
                   D   =  app.Kd.Value*errorDif/dt;                          % Türev    Kontrol Sinyali  hesaplandı
                  
                   PID =  P + I + D;                                         % O,I ve T kontrol sinyallerinin toplamıyla
                                                                             % PID Kontrol sinyalini elde ettik.

                   linearVel =  app.DogrusalHizSlider.Value;                 % Kullanıcı doğrusal hızı alındı                 
    
                   if(PID>Vmax) PID = Vmax; end                              % PID kontrol sinyali Vmax'ten büyük ise Vmax'i ata
                   
                   
                   if(currentAngle>=3*pi/2)                                  % Şuanki açı 3*pi/2 ' den büyük veya eşit mi?
                       currentAngle=-currentAngle;                           % Eşit ise açının tersini al
                   end
                   
                   if(currentAngle>pi && currentAngle<3*pi/2)                % Şuanki açı pi ile 3*pi/2 arasında mı?
                       currentAngle=-currentAngle;                           % öyle ise açının tersini al*
                   end
        
                   Vr = 0.5*linearVel*cos(-currentAngle)  +  PID;            % Sağ terleğe verilen gerilim
                   Vl = 0.5*linearVel*sin(-currentAngle)  +  PID;            % Sol terleğe verilen gerilim
                                                                             % "- currentAngle" Burada şuanki açının tersinin alınma
                                                                             % sebebi , robot istenen açıya varınca ileri devam etmesidir.
                                                                             % Aksi taktirde robot istenilen açıya varınca ters yöne gitmektedir.
                   
                   if(Vr>Vmax)  Vr =  Vmax; end                              % Vr gerilimi ,  Vmax'ten büyük ise Vmax'i ata
                   if(Vr<Vmin)  Vr =  Vmin; end                              % Vr gerilimi ,  Vmin'ten küçük ise Vmin'i ata
                   
                   if(Vl>Vmax)  Vl =  Vmax; end                              % Vl gerilimi ,  Vmax'ten büyük ise Vmax'i ata
                   if(Vl<Vmin)  Vl =  Vmin; end                              % Vl gerilimi ,  Vmin'ten küçük ise Vmin'i ata
                   
                    
                   angularVelocityRight = deg2rad(mlrobotreadwheelspeed(robot,'right'));    % Robotun sağ tekerleğin açısal hızı alındı [rad]
                   angularVelocityLeft  = deg2rad(mlrobotreadwheelspeed(robot, 'left'));    % Robotun sol tekerleğin açısal hızı alındı [rad]        
                                                                                       
                   errorArray(k)   =  Error;                        % k. hata, errorArray dizisine eklendi
                   vlArray(k)      =  Vl;                           % k. Vl, VlArray dizisine eklendi
                   vrArray(k)      =  Vr;                           % k. Vr, VrArray dizisine eklendi
                   angleArray(k)   =  currentAngle;                 % k. açı, angleArray dizisine eklendi
                   controlSig(k)   =  PID;                          % k. PID, kontrol sinyali controlSig dizisine eklendi
                   angularVelr(k)  =  angularVelocityRight;         % k. sağ tekerlek açısal hızı , angularVelr dizisine eklendi
                   angularVell(k)  =  angularVelocityLeft;          % k. sol tekerlek açısal hızı , angularVell dizisine eklendi
                   
                   
                   mlrobotsetmotorvoltage(app.myRobot,'right',Vr);  % Robotun sağ tekerleğine Vr gerilimi verildi
                   mlrobotsetmotorvoltage(app.myRobot,'left' ,Vl);  % Robotun sol tekerleğine Vl gerilimi verildi
                   
                   
                   fprintf('Açı  = %7.3f [rad]\n',currentAngle);    % Matlab komut satırına güncel Açı değeri yazdırıldı
                   fprintf('Hata = %7.3f [rad]\n',Error);           % Matlab komut satırına güncel Hata değeri yazdırıldı
                   fprintf('Vr   = %7.3f [volt]\n',Vr);             % Matlab komut satırına güncel Sağ tekerlek gerilim değeri yazdırıldı
                   fprintf('Vl   = %7.3f [volt]\n',Vl);             % Matlab komut satırına güncel Sol tekerlek gerilim değeri yazdırıldı
                   fprintf('PID  = %7.3f [Kontrol Sinyali]\n',PID); % Matlab komut satırına güncel PID kontrol Sinyal değeri yazdırıldı
                   
                   
                   line(app.graph_aci,0:dt:t(k),angleArray(1:k),'Color',color)             % Robotun  açı grafiği çizdrildi
                   grid on; hold on;                  
                   
                   line(app.graph_hata, 0:dt:t(k),errorArray(1:k),'Color',color)           % Hata grafiği çizdrildi
                   grid on; hold on;
                   
                   line(app.graph_tekerlek_hiz, 0:dt:t(k),angularVelr(1:k),'Color',color)  % Robotun sağ tekerlek açısal hız grafiği çizdrildi
                   grid on; hold on;
                   
                   line(app.graph_tekerlek_hiz, 0:dt:t(k),angularVell(1:k),'Color',color)  % Robotun sol tekerlek açısal hız grafiği çizdrildi
                   grid on; hold on;
                   
                   line(app.graph_kontrol, 0:dt:t(k),controlSig(1:k),'Color',color)        % PID kontrol sinyali grafiği çizdrildi
                   grid on; hold on;        
                   
                   line(app.graph_vr, 0:dt:t(k),vrArray(1:k),'Color',color)                % Vr grafiği çizdrildi (Sağ tekerleğe verilen gerilim)
                   grid on; hold on;                 
                   
                   line(app.graph_vl, 0:dt:t(k),vlArray(1:k),'Color',color)                % Vl grafiği çizdrildi (Sol tekerleğe verilen gerilim)
                   grid on; hold on;
                  
                   pause(dt);     % Robotun hareket etmesi için dt saniye (0.5s) bekletildi   
                  
                end
                   %Simülasyonun çalışma süresi 50s dolduğunda simülasyon durdurulur. 
                   mlrobotstop(robot);                                % Robot durduruldu     
                   app.running = 0;                                   % Uygulama durduruldu
                   app.SimulasyonButton.Text = 'Simulasyonu Başlat';  % App penceresi üzerindeki başlatma butonunun yazısı "Simulasyonu Başlat" ile değiştirildi. 
                              
                %_____----------_______-------_____--------_______-----__________------__________----------______---__________-------________-------______------%
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure = uifigure('Visible', 'off');
            app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure.Name = 'IZU Robot Teknolojileri Dersi Proje Odevi Mohamed TRAORE';
            app.DorusalHzSliderLabel = uilabel(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure);
            app.DorusalHzSliderLabel.Text = 'Doğrusal Hız';
            app.DogrusalHizSlider = uislider(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure);
            app.DogrusalHizSlider.Limits = [0 3];
            app.DogrusalHizSlider.MinorTicks = [];
            app.graph_aci = uiaxes(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure);
            title(app.graph_aci, 'Açı [rad]')
            xlabel(app.graph_aci, 't')
            app.graph_hata = uiaxes(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure);
            title(app.graph_hata, 'hata [rad]')
            xlabel(app.graph_hata, 't')
            app.graph_tekerlek_hiz = uiaxes(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure);
            title(app.graph_tekerlek_hiz, 'Tekerlek Açısal Hızları [rad/s]')
            xlabel(app.graph_tekerlek_hiz, 't')
            app.graph_kontrol = uiaxes(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure);
            title(app.graph_kontrol, 'Kontrol Sinyali (u)')
            xlabel(app.graph_kontrol, 't')
            app.graph_vl = uiaxes(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure);
            title(app.graph_vl, 'vl [volt]')
            xlabel(app.graph_vl, 't')
            app.graph_vr = uiaxes(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure);
            title(app.graph_vr, 'vr [volt]')
            xlabel(app.graph_vr, 't')
            app.SimulasyonButton = uibutton(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure, 'push');
            app.SimulasyonButton.ButtonPushedFcn = createCallbackFcn(app, @SimulasyonButtonPushed, true);
            app.SimulasyonButton.Text = 'Simulasyonu Başlat';
            app.KpEditFieldLabel = uilabel(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.Kp = uieditfield(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure, 'numeric');
            app.Kp.Value = 1;
            app.KiEditFieldLabel = uilabel(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.Ki = uieditfield(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure, 'numeric');
            app.KdEditField_2Label = uilabel(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure);
            app.KdEditField_2Label.Text = 'Kd';
            app.Kd = uieditfield(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure, 'numeric');
            app.AKnobLabel = uilabel(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure);
            app.AKnobLabel.Text = 'Açı';
            app.AciKnob = uiknob(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure, 'discrete');
            app.AciKnob.Items = {'0', 'pi/8', 'pi/4', '3*pi/8', 'pi/2', '5*pi/8', '6*pi/8', '7*pi/8', 'pi', '9*pi/8', '5*pi/4', '11*pi/8', '3*pi/2', '13*pi/8', '7*pi/4', '15*pi/8', '2*pi'};
            app.AciKnob.Value = '0';
            % Show the figure after all components are created
            app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proje

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.IZURobotTeknolojileriDersiProjeOdeviMohamedTRAOREUIFigure)
        end
    end
end